classdef NetworkViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Panel2_4                    matlab.ui.container.Panel
        GraphTypeDropDownLabel      matlab.ui.control.Label
        GraphTypeDropDown           matlab.ui.control.DropDown
        MeasureDropDownLabel        matlab.ui.control.Label
        MeasureDropDown             matlab.ui.control.DropDown
        GroupDropDownLabel          matlab.ui.control.Label
        GroupDropDown               matlab.ui.control.DropDown
        LoadDataButton              matlab.ui.control.Button
        EditField                   matlab.ui.control.EditField
        Panel2_5                    matlab.ui.container.Panel
        CompareToDropDownLabel      matlab.ui.control.Label
        CompareToDropDown           matlab.ui.control.DropDown
        MakeComparisonCheckBox      matlab.ui.control.CheckBox
        CompareTypeDropDownLabel    matlab.ui.control.Label
        CompareTypeDropDown         matlab.ui.control.DropDown
        AlphaEditFieldLabel         matlab.ui.control.Label
        AlphaEditField              matlab.ui.control.NumericEditField
        ThresholdCheckBox           matlab.ui.control.CheckBox
        Panel2_6                    matlab.ui.container.Panel
        CameraAngleDropDownLabel    matlab.ui.control.Label
        CameraAngleDropDown         matlab.ui.control.DropDown
        HubDataDropDownLabel        matlab.ui.control.Label
        HubDataDropDown             matlab.ui.control.DropDown
        ApplyHubCheckBox            matlab.ui.control.CheckBox
        ButtonGroup                 matlab.ui.container.ButtonGroup
        WholeButton                 matlab.ui.control.RadioButton
        LeftButton                  matlab.ui.control.RadioButton
        RightButton                 matlab.ui.control.RadioButton
        HubThreshDropDownLabel      matlab.ui.control.Label
        HubThreshDropDown           matlab.ui.control.DropDown
        BallCheckBox                matlab.ui.control.CheckBox
        SticksCheckBox              matlab.ui.control.CheckBox
        ThreshPctileEditFieldLabel  matlab.ui.control.Label
        ThreshPctileEditField       matlab.ui.control.NumericEditField
        HidenonHubNodesCheckBox     matlab.ui.control.CheckBox
        ThreshPctileLabel           matlab.ui.control.Label
        ThreshPctileEditField_2     matlab.ui.control.NumericEditField
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        LabelLUT
        Mesh
        viewA
        viewE
        CRange
        flipCmap
        valDisp
        allResults
        GraphType
        MeasureName
        Group1Index
        Group2Index
        MakeComparison
        Threshold
        CompareType
        Alpha
        ShowBrain
        ApplyHub
        HubGroupIndex
        HubThreshGroupIndex
        HCIndex
        svIndex
        naIndex
        LeftIdx
        RightIdx
        BallFlag
        StickFlag
        StickThreshGreater
        StickThreshLesser
        CoM
        CompareToTest
        TestVals
        SubInfo
        HideHubs
    end
    
    methods (Access = private)
        function setValDisp(app)
            
            if(~isfield(app.allResults,app.GraphType) || ~isfield(app.allResults.(app.GraphType),(app.MeasureName)))
                return
            end
            
            app.flipCmap = 0;
            if(~app.MakeComparison)
                data = nanmean(app.allResults.(app.GraphType).(app.MeasureName)(app.Group1Index,:),1);
                app.CRange=([0 prctile(data(:),95)]);
                
            else
                numLab=448;
                G1 = app.allResults.(app.GraphType).(app.MeasureName)(app.Group1Index,:);
                
                if(~app.CompareToTest)
                    
                    G2 = app.allResults.(app.GraphType).(app.MeasureName)(app.Group2Index,:);
                    
                    TTestsAll_pVal = nan(1,numLab);
                    TTestsAll_tstat = nan(1,numLab);
                    %do t-test on all labels
                    for t = 1:numLab
                        [h1 p ci stats]=ttest2(G1(:,t),G2(:,t),app.Alpha,'left');
                        TTestsAll_pVal(t)=p;
                        TTestsAll_tstat(t) = stats.tstat;
                    end
                    
                    notNaN = ~isnan(TTestsAll_pVal);
                    useTstat = 0;
                    switch app.CompareType
                        
                        case 'tStat'
                            useTstat=1;
                        case 'p-val (FDR)'
                            [padj,alpha] = multicmp(TTestsAll_pVal(notNaN),'fdr',app.Alpha);
                        case 'p-val (FWE-Holm)'
                            [padj,alpha] = multicmp(TTestsAll_pVal(notNaN),'down',app.Alpha);
                        case 'p-val (FWE-Hochberg)'
                            [padj,alpha] = multicmp(TTestsAll_pVal(notNaN),'up',app.Alpha);
                            
                    end
                    
                    if(useTstat)
                        data = TTestsAll_tstat;
                        app.CRange=[-4 4];
                    else
                        data = TTestsAll_pVal;
                        data(~notNaN) = 1;
                        data(notNaN) = padj;
                        app.CRange=[0 1];
                        if(app.Threshold)
                            data = double(data<app.Alpha);
                        else
                            app.flipCmap = 1;
                        end
                    end
                else
                    G1TestVals = app.TestVals(app.Group1Index);
                    corrAlll = nan(1,numLab);
                    %do t-test on all labels
                    for t = 1:numLab
                        corrAlll(t) = corr(G1(:,t),G1TestVals, 'rows','complete');
                    end
                    
                    data = corrAlll;
                    app.CRange=[-1 1];
                    
                end
                
            end


            %find and apply hubs if needed
            if(app.ApplyHub && isfield(app.allResults.(app.GraphType),('BetweenCen')) && isfield(app.allResults.(app.GraphType),('Degree')))
                betweenMean = nanmean(app.allResults.(app.GraphType).('BetweenCen')(app.HubGroupIndex,:),1);
                degreeMean = nanmean(app.allResults.(app.GraphType).('Degree')(app.HubGroupIndex,:),1);
                
                a = nanmean(app.allResults.(app.GraphType).('BetweenCen')(app.HubThreshGroupIndex,:),1);
                betweenThresh = nanmean(a) + std(a,'omitnan');
                a = nanmean(app.allResults.(app.GraphType).('Degree')(app.HubThreshGroupIndex,:),1);
                degreeThresh = nanmean(a) + std(a,'omitnan');
                
                a=betweenMean>=betweenThresh;
                b=degreeMean>=degreeThresh;
                hubs = a|b;
                
                if(app.flipCmap)
                    data(~hubs)=1;
                else
                    data(~hubs)=0;
                end
                
                if(app.HideHubs)
                    data(~hubs)=nan;
                end
                
            end
            
            %updatemesh values
            app.valDisp=app.Mesh.attributes.attribute_array;
            meshLabels = app.Mesh.attributes.attribute_array;
            N = length(data);
            for i = 1:N
                currLabel = app.LabelLUT{i,1};
                app.valDisp(meshLabels==currLabel) = data(i);
            end
            
            
            updatePlot(app,data);
        end
        
        function updatePlot(app,data)
            switch app.ShowBrain
                case 'Whole'
                case 'Right'
                    app.valDisp(app.LeftIdx)=nan;
                    data(app.LabelLUT{:,1}>223)=nan;
                case 'Left'
                    app.valDisp(app.RightIdx)=nan;
                    data(app.LabelLUT{:,1}<=223)=nan;
            end
            
            
            if(app.BallFlag)
                if(~app.StickFlag)
                    scatter3(app.UIAxes,app.CoM(:,1),app.CoM(:,2),app.CoM(:,3),[],data);
                elseif(app.StickFlag)
                    data(data==0)=nan;
                    scatter3(app.UIAxes,app.CoM(:,1),app.CoM(:,2),app.CoM(:,3),(100*(data-min(data))/(max(data)-min(data)))+1,'white');
                    hold(app.UIAxes,"on")
                    LN = length(app.CoM(:,1));


                    if(~app.MakeComparison || app.CompareToTest)
                        allmtx=app.allResults.(app.GraphType).('WeightMtx')(app.Group1Index);
                        meanmtx = allmtx{1};
                        for k = 2:length(allmtx)
                            meanmtx = meanmtx+allmtx{k};
                        end
                        meanmtx=meanmtx./length(allmtx);
                    else
                        
                        G1 = app.allResults.(app.GraphType).('WeightMtx')(app.Group1Index);
                        G2 = app.allResults.(app.GraphType).('WeightMtx')(app.Group2Index);
                        
                        G1mtx =  cat(3, G1{:});
                        G2mtx =  cat(3, G2{:});
                        TTestsAll_pVal = nan(LN,LN);
                        TTestsAll_tstat = nan(LN,LN);
                        %do t-test on all labels
                        for i = 1:LN
                            for j = i:LN
                                
                                if (~(app.ApplyHub && isnan(data(i)) && isnan(data(j))))
                                    [h1 p ci stats]=ttest2(G1mtx(i,j,:),G2mtx(i,j,:),app.Alpha,'left');
                                    TTestsAll_pVal(i,j)=p;
                                    TTestsAll_tstat(i,j) = stats.tstat;
                                end
                            end
                        end
                        notNaN = ~isnan(TTestsAll_pVal);
                        useTstat = 0;
                        switch app.CompareType
                            
                            case 'tStat'
                                useTstat=1;
                            case 'p-val (FDR)'
                                [padj,alpha] = multicmp(TTestsAll_pVal(notNaN),'fdr',app.Alpha);
                            case 'p-val (FWE-Holm)'
                                [padj,alpha] = multicmp(TTestsAll_pVal(notNaN),'down',app.Alpha);
                            case 'p-val (FWE-Hochberg)'
                                [padj,alpha] = multicmp(TTestsAll_pVal(notNaN),'up',app.Alpha);
                                
                        end
                        
                        if(useTstat)
                            meanmtx = TTestsAll_tstat;
                        else
                            meanmtx = TTestsAll_pVal;
                            meanmtx(~notNaN) = 1;
                            meanmtx(notNaN) = padj;
                        end
                    end


                    colors = jet(1000);
                    
                        threshGreater = prctile(meanmtx(meanmtx>0),app.StickThreshGreater);
                        threshLesser = prctile(meanmtx(meanmtx>0),app.StickThreshLesser);
                    
                    if(app.MakeComparison)
                        valMax = 4;
                        valMin = -4;
                        
                    else
                        if (app.StickThreshGreater >= app.StickThreshLesser)
                            valMax = max(meanmtx(:));
                            valMin = min(meanmtx(:));
                        else
                            valMax = threshLesser*2;
                            valMin = threshGreater/2;
                        end
                    end
                        app.CRange = [valMin valMax];
                    
                    for i = 1:LN
                        for j = i:LN
                            x =[app.CoM(i,1) ; app.CoM(j,1)'];
                            y = [app.CoM(i,2) ; app.CoM(j,2)'];
                            z = [app.CoM(i,3) ; app.CoM(j,3)'];
                            
                            if(meanmtx(i,j)==0)
                                inRangeFlag = 0;
                            elseif(app.ApplyHub && isnan(data(i)) && isnan(data(j)))
                                inRangeFlag = 0;
                            else
                                if (app.StickThreshGreater >= app.StickThreshLesser)
                                    inRangeFlag=(meanmtx(i,j)>threshGreater || meanmtx(i,j) < threshLesser);
                                else
                                    inRangeFlag=(meanmtx(i,j)>threshGreater && meanmtx(i,j) < threshLesser);
                                end
                            end
                            if(inRangeFlag)
                                val = (meanmtx(i,j)-valMin)/(valMax-valMin);
                                %10*val+.
                                plot3(app.UIAxes,x,y,z,'linewidth',1,'color',colors(max(round(1000*val),1),:));
                            end
                        end
                    end
                    hold(app.UIAxes,"off")
                    
                end
            else
                trisurf(app.Mesh.triangles,app.Mesh.points(:,1),app.Mesh.points(:,2),app.Mesh.points(:,3),app.valDisp(:),'EdgeColor','none','Parent', app.UIAxes);
            end
            set(app.UIAxes, 'color', 'k');
            lighting(app.UIAxes,'gouraud');
            a1 = camlight(app.UIAxes,'right');
            a2 = camlight(app.UIAxes,'left');
            a3 = camlight(app.UIAxes,'headlight');
            a4 = camlight(app.UIAxes,0,-90);
            axis(app.UIAxes,'equal');
            view(app.UIAxes,app.viewA,app.viewE)


            c = jet;
            if(app.flipCmap)
                c = flipud(c);
            end
            caxis(app.UIAxes,app.CRange)
            colormap(app.UIAxes,c);
            c = colorbar(app.UIAxes);
            xlim(app.UIAxes,[-100 100])
            ylim(app.UIAxes,[-120 80])
            zlim(app.UIAxes,[-100 100])
        end
        
        function updateView(app)
            view(app.UIAxes,app.viewA,app.viewE)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            addpath(genpath(fullfile('..','MedicalImageProcessingToolbox')))
            app.LabelLUT = readtable('Lausanne_Scale250.csv');
            loadMesh = load('lausanne250_mesh.mat');
            app.Mesh = loadMesh.mesh;
            %            LabelVol = niftiread('./Lausanne_Scale250.nii.gz');
            meshlabels = app.Mesh.attributes.attribute_array;
            app.CoM=zeros(height(app.LabelLUT),3);
            for i = 1:height(app.LabelLUT)
                currLabel = app.LabelLUT{i,1};
                idx = find(meshlabels==currLabel);
                %                [r,c,v] = ind2sub(size(LabelVol),find(LabelVol==currLabel));
                app.CoM(i,:)=mean(app.Mesh.points(idx,:),1);
            end


            inInfo = 'subj95_baseline.xlsx';
            T = readtable(inInfo);
            app.SubInfo = T;
            app.HCIndex =strcmpi(T.StudyGroup,'eld');
            app.svIndex =strcmpi(T.StudyGroup,'svPPA');
            app.naIndex =strcmpi(T.StudyGroup,'naPPA');
            app.Group1Index=app.HCIndex;
            app.Group2Index=app.HCIndex;
            %default Values
            app.GraphType='Thickness';
            app.MeasureName='Mean';
            
            app.BallFlag=0;
            app.StickFlag=0;
            app.StickThreshGreater=95;
            app.StickThreshLesser=100;
            
            
            app.MakeComparison=0;
            app.Threshold=0;
            app.CompareType='tStat';
            app.Alpha=0.05;
            app.ShowBrain='Whole';
            app.ApplyHub = 0;
            app.HideHubs = 0;
            app.HubGroupIndex=app.HCIndex;
            app.HubThreshGroupIndex=app.HCIndex;
            app.CompareToTest=0;
            app.TestVals=[];


            app.valDisp = app.Mesh.attributes.attribute_array;
            app.LeftIdx = app.valDisp>223;
            app.RightIdx = app.valDisp<=223;
            
            
            app.viewA=0;
            app.viewE=90;
            app.CRange=[0 500];
            app.flipCmap=0;
            
            updatePlot(app)
        end

        % Value changed function: CameraAngleDropDown
        function CameraAngleDropDownValueChanged(app, event)
            value = app.CameraAngleDropDown.Value;
            
            switch value
                case 'Dorsal'
                    app.viewA=0;
                    app.viewE=90;
                case 'Ventral'
                    app.viewA=-180;
                    app.viewE=-90;
                case 'Left'
                    app.viewA=-90;
                    app.viewE=15;
                case 'Right'
                    app.viewA=90;
                    app.viewE=15;
            end
            updateView(app)
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [filename, pathname] = uigetfile();
            app.EditField.Value=filename;
            figure(app.UIFigure)
            allResultsIn = load(fullfile(pathname,filename));
            app.allResults=allResultsIn.AllResults;
            setValDisp(app)
        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            app.GraphType = app.GraphTypeDropDown.Value;
            setValDisp(app)
        end

        % Value changed function: MeasureDropDown
        function MeasureDropDownValueChanged(app, event)
            app.MeasureName = app.MeasureDropDown.Value;
            setValDisp(app)
        end

        % Value changed function: GroupDropDown
        function GroupDropDownValueChanged(app, event)
            value = app.GroupDropDown.Value;
            
            switch value
                case 'Healthy Controls'
                    app.Group1Index=app.HCIndex;
                case 'svPPA'
                    app.Group1Index=app.svIndex;
                case 'naPPA'
                    app.Group1Index=app.naIndex;
            end
            setValDisp(app)
        end

        % Value changed function: MakeComparisonCheckBox
        function MakeComparisonCheckBoxValueChanged(app, event)
            app.MakeComparison = app.MakeComparisonCheckBox.Value;
            setValDisp(app)
        end

        % Value changed function: ThresholdCheckBox
        function ThresholdCheckBoxValueChanged(app, event)
            app.Threshold = app.ThresholdCheckBox.Value;
            setValDisp(app)
            
        end

        % Value changed function: CompareToDropDown
        function CompareToDropDownValueChanged(app, event)
            value = app.CompareToDropDown.Value;
            switch value
                case 'Healthy Controls'
                    app.Group2Index=app.HCIndex;
                    app.CompareToTest=0;
                case 'svPPA'
                    app.Group2Index=app.svIndex;
                    app.CompareToTest=0;
                case 'naPPA'
                    app.Group2Index=app.naIndex;
                    app.CompareToTest=0;
                case 'BNT'
                    app.TestVals=app.SubInfo.('BNT');
                    app.CompareToTest=1;
                case 'CookieFluency'
                    app.TestVals=app.SubInfo.('CookieFluency');
                    app.CompareToTest=1;
                case 'AnimalsTotal'
                    app.TestVals=app.SubInfo.('AnimalsTotal');
                    app.CompareToTest=1;
                case 'FWordsCorrect'
                    app.TestVals=app.SubInfo.('FWordsCorrect');
                    app.CompareToTest=1;
            end
            setValDisp(app)
        end

        % Value changed function: CompareTypeDropDown
        function CompareTypeDropDownValueChanged(app, event)
            app.CompareType = app.CompareTypeDropDown.Value;
            setValDisp(app)
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.Alpha = app.AlphaEditField.Value;
            setValDisp(app)
        end

        % Value changed function: ApplyHubCheckBox
        function ApplyHubCheckBoxValueChanged(app, event)
            app.ApplyHub = app.ApplyHubCheckBox.Value;
            setValDisp(app)
        end

        % Value changed function: HubDataDropDown
        function HubDataDropDownValueChanged(app, event)
            value = app.HubDataDropDown.Value;
            switch value
                case 'Healthy Controls'
                    app.HubGroupIndex=app.HCIndex;
                case 'svPPA'
                    app.HubGroupIndex=app.svIndex;
                case 'naPPA'
                    app.HubGroupIndex=app.naIndex;
            end
            setValDisp(app)
        end

        % Value changed function: HubThreshDropDown
        function HubThreshDropDownValueChanged(app, event)
            value = app.HubThreshDropDown.Value;
            switch value
                case 'Healthy Controls'
                    app.HubThreshGroupIndex=app.HCIndex;
                case 'svPPA'
                    app.HubThreshGroupIndex=app.svIndex;
                case 'naPPA'
                    app.HubThreshGroupIndex=app.naIndex;
            end
            setValDisp(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.ShowBrain = selectedButton.Text;
            setValDisp(app)
        end

        % Value changed function: BallCheckBox
        function BallCheckBoxValueChanged(app, event)
            app.BallFlag = app.BallCheckBox.Value;
            setValDisp(app)
        end

        % Value changed function: SticksCheckBox
        function SticksCheckBoxValueChanged(app, event)
            app.StickFlag = app.SticksCheckBox.Value;
            setValDisp(app)
        end

        % Value changed function: ThreshPctileEditField
        function ThreshPctileEditFieldValueChanged(app, event)
            app.StickThreshGreater = app.ThreshPctileEditField.Value;
            setValDisp(app)
        end

        % Value changed function: HidenonHubNodesCheckBox
        function HidenonHubNodesCheckBoxValueChanged(app, event)
            app.HideHubs = app.HidenonHubNodesCheckBox.Value;
            setValDisp(app)
        end

        % Value changed function: ThreshPctileEditField_2
        function ThreshPctileEditField_2ValueChanged(app, event)
            app.StickThreshLesser = app.ThreshPctileEditField_2.Value;
            setValDisp(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {722, 722};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'NetworkViewer';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Data';
            app.GraphTypeDropDownLabel = uilabel(app.Panel2_4);
            app.GraphTypeDropDownLabel.Text = 'Graph Type';
            app.GraphTypeDropDown = uidropdown(app.Panel2_4);
            app.GraphTypeDropDown.Items = {'Thickness', 'SCGraph', 'FAGraph'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'Thickness';
            app.MeasureDropDownLabel = uilabel(app.Panel2_4);
            app.MeasureDropDownLabel.Text = 'Measure';
            app.MeasureDropDown = uidropdown(app.Panel2_4);
            app.MeasureDropDown.Items = {'Degree', 'BetweenCen', 'ClusterCoeff', 'EigenCen', 'Mean', 'Median'};
            app.MeasureDropDown.ValueChangedFcn = createCallbackFcn(app, @MeasureDropDownValueChanged, true);
            app.MeasureDropDown.Value = 'Mean';
            app.GroupDropDownLabel = uilabel(app.Panel2_4);
            app.GroupDropDownLabel.Text = 'Group';
            app.GroupDropDown = uidropdown(app.Panel2_4);
            app.GroupDropDown.Items = {'Healthy Controls', 'svPPA', 'naPPA'};
            app.GroupDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupDropDownValueChanged, true);
            app.GroupDropDown.Value = 'Healthy Controls';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'LoadData';
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.Panel2_5 = uipanel(app.LeftPanel);
            app.Panel2_5.Title = 'Comparisons';
            app.CompareToDropDownLabel = uilabel(app.Panel2_5);
            app.CompareToDropDownLabel.Text = 'Compare To';
            app.CompareToDropDown = uidropdown(app.Panel2_5);
            app.CompareToDropDown.Items = {'Healthy Controls', 'svPPA', 'naPPA', 'BNT', 'CookieFluency', 'AnimalsTotal', 'FWordsCorrect'};
            app.CompareToDropDown.ValueChangedFcn = createCallbackFcn(app, @CompareToDropDownValueChanged, true);
            app.CompareToDropDown.Value = 'Healthy Controls';
            app.MakeComparisonCheckBox = uicheckbox(app.Panel2_5);
            app.MakeComparisonCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakeComparisonCheckBoxValueChanged, true);
            app.MakeComparisonCheckBox.Text = 'Make Comparison';
            app.CompareTypeDropDownLabel = uilabel(app.Panel2_5);
            app.CompareTypeDropDownLabel.Text = 'Compare Type';
            app.CompareTypeDropDown = uidropdown(app.Panel2_5);
            app.CompareTypeDropDown.Items = {'tStat', 'p-val (FDR)', 'p-val (FWE-Holm)', 'p-val (FWE-Hochberg)'};
            app.CompareTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CompareTypeDropDownValueChanged, true);
            app.CompareTypeDropDown.Value = 'tStat';
            app.AlphaEditFieldLabel = uilabel(app.Panel2_5);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.Panel2_5, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 0.05;
            app.ThresholdCheckBox = uicheckbox(app.Panel2_5);
            app.ThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThresholdCheckBoxValueChanged, true);
            app.ThresholdCheckBox.Text = 'Threshold';
            app.Panel2_6 = uipanel(app.LeftPanel);
            app.Panel2_6.Title = 'Views';
            app.CameraAngleDropDownLabel = uilabel(app.Panel2_6);
            app.CameraAngleDropDownLabel.Text = 'Camera Angle';
            app.CameraAngleDropDown = uidropdown(app.Panel2_6);
            app.CameraAngleDropDown.Items = {'Dorsal', 'Ventral', 'Left', 'Right'};
            app.CameraAngleDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraAngleDropDownValueChanged, true);
            app.CameraAngleDropDown.Value = 'Dorsal';
            app.HubDataDropDownLabel = uilabel(app.Panel2_6);
            app.HubDataDropDownLabel.Text = 'Hub Data';
            app.HubDataDropDown = uidropdown(app.Panel2_6);
            app.HubDataDropDown.Items = {'Healthy Controls', 'svPPA', 'naPPA'};
            app.HubDataDropDown.ValueChangedFcn = createCallbackFcn(app, @HubDataDropDownValueChanged, true);
            app.HubDataDropDown.Value = 'Healthy Controls';
            app.ApplyHubCheckBox = uicheckbox(app.Panel2_6);
            app.ApplyHubCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyHubCheckBoxValueChanged, true);
            app.ApplyHubCheckBox.Text = 'Apply Hub';
            app.ButtonGroup = uibuttongroup(app.Panel2_6);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Show Brain';
            app.WholeButton = uiradiobutton(app.ButtonGroup);
            app.WholeButton.Text = 'Whole';
            app.WholeButton.Value = true;
            app.LeftButton = uiradiobutton(app.ButtonGroup);
            app.LeftButton.Text = 'Left';
            app.RightButton = uiradiobutton(app.ButtonGroup);
            app.RightButton.Text = 'Right';
            app.HubThreshDropDownLabel = uilabel(app.Panel2_6);
            app.HubThreshDropDownLabel.Text = 'Hub Thresh';
            app.HubThreshDropDown = uidropdown(app.Panel2_6);
            app.HubThreshDropDown.Items = {'Healthy Controls', 'svPPA', 'naPPA'};
            app.HubThreshDropDown.ValueChangedFcn = createCallbackFcn(app, @HubThreshDropDownValueChanged, true);
            app.HubThreshDropDown.Value = 'Healthy Controls';
            app.BallCheckBox = uicheckbox(app.Panel2_6);
            app.BallCheckBox.ValueChangedFcn = createCallbackFcn(app, @BallCheckBoxValueChanged, true);
            app.BallCheckBox.Text = 'Ball';
            app.SticksCheckBox = uicheckbox(app.Panel2_6);
            app.SticksCheckBox.ValueChangedFcn = createCallbackFcn(app, @SticksCheckBoxValueChanged, true);
            app.SticksCheckBox.Text = 'Sticks';
            app.ThreshPctileEditFieldLabel = uilabel(app.Panel2_6);
            app.ThreshPctileEditFieldLabel.Text = 'Thresh > Pctile';
            app.ThreshPctileEditField = uieditfield(app.Panel2_6, 'numeric');
            app.ThreshPctileEditField.Limits = [0 100];
            app.ThreshPctileEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshPctileEditFieldValueChanged, true);
            app.ThreshPctileEditField.Value = 95;
            app.HidenonHubNodesCheckBox = uicheckbox(app.Panel2_6);
            app.HidenonHubNodesCheckBox.ValueChangedFcn = createCallbackFcn(app, @HidenonHubNodesCheckBoxValueChanged, true);
            app.HidenonHubNodesCheckBox.Text = 'Hide non-Hub Nodes';
            app.ThreshPctileLabel = uilabel(app.Panel2_6);
            app.ThreshPctileLabel.Text = 'Thresh < Pctile';
            app.ThreshPctileEditField_2 = uieditfield(app.Panel2_6, 'numeric');
            app.ThreshPctileEditField_2.Limits = [0 100];
            app.ThreshPctileEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThreshPctileEditField_2ValueChanged, true);
            app.ThreshPctileEditField_2.Value = 100;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end